classdef BusinessLogic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    properties (Access = private)


    end
    
    properties (Access = public)
        myPlayerNum % Am I player 1 or player 2?
        otherPlayerNum % The number of the player whose turn is after mine % Description
        channelID % your Channel ID
        writeKey % channel Write API Key
        readKey  % channel Read API Key
        readDelay % wait 5 seconds between ThingSpeak Channel reads
        writeDelay 
        currentRoundScore=0
        myScore=0
        otherScore=0
        finalScore= 20
        isGameOver= false
        currentRoll

    end


    methods (Access = private)
        
    end


    methods (Access = public)


        % random number generator
        function rollValue = rollDice(~)
            rollValue = randi([2, 6]);

        end


        % resets current round score
        function resetScore(app)
            app.currentRoundScore = 0;

        end


        % sends the current player number and their score
        function [] = SendDataToOtherPlayer(app)
            pause(app.writeDelay);

            if app.myScore >= app.finalScore
                dataString = strcat(string(app.myPlayerNum), " ", ...
                    string(app.myScore)," ","GameOver", " ");


            else
                dataString = strcat(string(app.myPlayerNum), " ", ...
                    string(app.myScore)," ");

            end


            disp("I sent this data to the ThingSpeak channel: ");
            disp(dataString);

            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', dataString ...
                ,'WriteKey', app.writeKey);
        end


        % resets/initializes thingspeak
        function [] = ClearThinkSpeakChannel(app)
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', "0", 'WriteKey', app.writeKey);

        end


        % extracts value from the write message, sets the
        % otherplayer's score and sets the isGameOver value if the other
        % player is the winner

        function value= WaitForOtherPlayer(app)
            value = false;
            playerWhoSentMsg = 0;

            while playerWhoSentMsg ~= app.otherPlayerNum

                dataTable = thingSpeakRead(app.channelID, 'Fields', 1, 'ReadKey', ...
                    app.readKey, 'OutputFormat','Table');


                dataString = string(dataTable.dataString(1));

                % to help with debugging
                disp("I am player " + num2str(app.myPlayerNum) ...
                    + " and I just read this data from the ThingSpeak channel:");
                disp(dataString);

                % split data string into separate numbers
                dataParts = split(strip(dataString));

                % the first number in the string should
                % always be the number of the player who
                % last wrote the data
                playerWhoSentMsg = str2num(dataParts(1));

                % delay before trying to read the online channel again
                pause(app.readDelay);
            end

            app.otherScore= str2num(dataParts(2));

            if contains(dataString, "GameOver")


                disp("You Lost! The game is over.");
                app.isGameOver= true;

            end

        end


        % main calculuation method: adds/subtracts total value
        function [turnOver, rollValue, message] = takeTurn(app)

            turnOver= false;
            rollValue = app.rollDice();
            app.currentRoll = rollValue;
            if rollValue == 1
                app.myScore = app.myScore - app.currentRoundScore;
                app.currentRoundScore = 0;
                turnOver= true;
                message = sprintf("Rolled a 1! Player %d's Turn", app.otherPlayerNum);

                return

            else
                app.currentRoundScore = app.currentRoundScore + rollValue;
                app.myScore = app.myScore + rollValue;
                message = sprintf("You rolled a %d!", rollValue);
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            app.myPlayerNum = 1;
            app.otherPlayerNum = 2;
            app.channelID = 2863781;
            app.writeKey = 'XTW9Z76ACCNZV6UM';
            app.readKey = '06IPT59LPJ8QQP7Z';
            app.readDelay = 1;
            app.writeDelay = 1;
            app.ClearThinkSpeakChannel();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BusinessLogic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end