classdef gameGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        StartPanel          matlab.ui.container.Panel
        StartButton         matlab.ui.control.Button
        Image6              matlab.ui.control.Image
        Image5              matlab.ui.control.Image
        RulesButton         matlab.ui.control.Button
        ButtonGroup         matlab.ui.container.ButtonGroup
        Player2Button       matlab.ui.control.RadioButton
        Player1Button       matlab.ui.control.RadioButton
        Image4              matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
        Player2ScoreLabel   matlab.ui.control.Label
        Player1ScoreLabel   matlab.ui.control.Label
        CurrentPlayerLabel  matlab.ui.control.Label
        RollDiceButton      matlab.ui.control.Button
        HoldButton          matlab.ui.control.Button
        BeginButton         matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        DiceAxes            matlab.ui.control.UIAxes
        background          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        logic = BusinessLogic()
        myTurn
        otherTurn
        myStats
        otherStats
        
    end


   methods (Access = public)
   end

   
   methods (Access = private)

    % dice animation
    function animation(app, numRoll)

        if numRoll == 1
            dice1 = VideoReader("diceroll1.mp4");
            while hasFrame(dice1)
                frame = readFrame(dice1);
                imshow(frame, 'parent', app.DiceAxes)
                pause(0.01);
            end
        end

        if numRoll == 2
            dice1 = VideoReader("diceroll2.mp4");
            while hasFrame(dice1)
                frame = readFrame(dice1);
                imshow(frame, 'parent', app.DiceAxes)
                pause(0.01);
            end
        end


        if numRoll == 3
            dice1 = VideoReader("diceroll3.mp4");
            while hasFrame(dice1)
                frame = readFrame(dice1);
                imshow(frame, 'parent', app.DiceAxes)
                pause(0.01);
            end
        end

        if numRoll == 4
            dice1 = VideoReader("diceroll4.mp4");
            while hasFrame(dice1)
                frame = readFrame(dice1);
                imshow(frame, 'parent', app.DiceAxes)
                pause(0.01);
            end
        end

        if numRoll == 5
            dice1 = VideoReader("diceroll5.mp4");
            while hasFrame(dice1)
                frame = readFrame(dice1);
                imshow(frame, 'parent', app.DiceAxes)
                pause(0.01);
            end
        end

        if numRoll == 6
            dice1 = VideoReader("diceroll6.mp4");
            while hasFrame(dice1)
                frame = readFrame(dice1);
                imshow(frame, 'parent', app.DiceAxes)
                pause(0.01);
            end
        end
    end


    % updates the UI (just the player turn) when hold is pressed 
    function updateUIHold(app)
        if app.logic.myPlayerNum == 1
            app.Player1Button.Value = true;
            app.CurrentPlayerLabel.Text = "Player 2's Turn!";
        else
            app.Player2Button.Value = true;
            app.CurrentPlayerLabel.Text = "Player 1's Turn!";
        end

    end


    % updates the UI when 1 is rolled with scores
    function updateFor1(app)

        app.myStats= app.logic.myScore;
        app.otherStats= app.logic.otherScore;
        app.myTurn= app.logic.myPlayerNum;
        app.otherTurn= app.logic.otherPlayerNum;

        if(app.myTurn==1)

            app.Player1ScoreLabel.Text = num2str(app.myStats);
            app.Player2ScoreLabel.Text = num2str(app.otherStats);
            app.CurrentPlayerLabel.Text = "Player 2's Turn!";

        end

        if(app.myTurn==2)

            app.Player2ScoreLabel.Text = num2str(app.myStats);
            app.Player1ScoreLabel.Text = num2str(app.otherStats);
            app.CurrentPlayerLabel.Text = "Player 1's Turn!";

        end

    end


      % updates both scores and player turns; also updates for the losing
      % player
      function updateUI(app)

            app.myStats= app.logic.myScore;
            app.otherStats= app.logic.otherScore;
            app.myTurn= app.logic.myPlayerNum;
            app.otherTurn= app.logic.otherPlayerNum;


            if app.logic.isGameOver
                app.Player2ScoreLabel.Text = num2str(app.myStats);
                app.Player1ScoreLabel.Text = num2str(app.otherStats);
                display("You're out");
                app.disableGameButtons();
                app.CurrentPlayerLabel.Text = sprintf("You lost");
                pause(90);
                quit;
            end


            if(app.myTurn==1)
                app.Player1ScoreLabel.Text = num2str(app.myStats);
                app.Player2ScoreLabel.Text = num2str(app.otherStats);
                app.CurrentPlayerLabel.Text = sprintf("Player %d's Turn!", app.myTurn);
                return;

            end


            if(app.myTurn==2)
                app.Player2ScoreLabel.Text = num2str(app.myStats);
                app.Player1ScoreLabel.Text = num2str(app.otherStats);
                app.CurrentPlayerLabel.Text = sprintf("Player %d's Turn!", app.myTurn);
                return;

            end

        end


        % checks for winner and updates for the winner
        function result= checkWinner(app)

            result=false;
            winningScore = app.logic.finalScore;

            if app.logic.myScore >= winningScore
                result= true;
                winnerMessage = sprintf("Player %d Wins!", app.logic.myPlayerNum);
                app.CurrentPlayerLabel.Text = winnerMessage;
                disp(winnerMessage);
                app.myStats= app.logic.myScore;
                app.otherStats= app.logic.otherScore;
                app.myTurn= app.logic.myPlayerNum;
                app.otherTurn= app.logic.otherPlayerNum;

                if(app.myTurn==1)
                    app.Player1ScoreLabel.Text = num2str(app.myStats);
                    app.Player2ScoreLabel.Text = num2str(app.otherStats);
                end


                if(app.myTurn==2)
                    app.Player2ScoreLabel.Text = num2str(app.myStats);
                    app.Player1ScoreLabel.Text = num2str(app.otherStats);

                end

                disableGameButtons(app);
                pause(5);
               
            end
        end


        % disable Roll and Hold buttons when the game is over
        function disableGameButtons(app)
            set(app.RollDiceButton, 'Enable', 'off');
            set(app.HoldButton, 'Enable', 'off');
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.background.Position = [0 0 app.background.Position(3) app.background.Position(4)];
            bg = imread('game bg.png');
            imshow(bg, 'Parent', app.background);
            set(app.background, 'XTick', [], 'YTick', [], 'XColor', 'none', 'YColor', 'none');
            set(app.background, 'XLim', [1 size(bg,2)], 'YLim', [1 size(bg,1)]);
            axis(app.background, 'image'); 
            app.StartPanel.Visible = 'on'; 
            set(app.RollDiceButton, 'Enable', 'off');
            set(app.HoldButton, 'Enable', 'off');
            img = imread('diceroll.png'); 
            imshow(img, 'Parent', app.DiceAxes);
        
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
            %sound effect for button
            [y,Fs] = audioread('dice_roll.wav');
            sound(y,Fs);
            
            app.CurrentPlayerLabel.Text = "Rolling...";
            [turnOver,numRoll, message] = app.logic.takeTurn();
            animation(app,numRoll);
            pause(1);
            result= checkWinner(app);
            display("rollbutton")
            %table
            oldData = app.UITable.Data;

            if isempty(oldData)
                newData = {app.logic.currentRoll}; 
            else
                newData = [oldData; {app.logic.currentRoll}]; 
            end

            app.UITable.Data = newData;
            
            if turnOver
                app.UITable.Data = [];
                app.CurrentPlayerLabel.Text= message;
                set(app.RollDiceButton, 'Enable', 'off');
                set(app.HoldButton, 'Enable', 'off');
                pause(2);
                app.updateFor1();
                app.logic.resetScore();
                display("turnOver")
                app.logic.SendDataToOtherPlayer();                
                value= app.logic.WaitForOtherPlayer();
                app.updateUI();
                set(app.RollDiceButton, 'Enable', 'on');
                set(app.HoldButton, 'Enable', 'on');

            end

            if result

                display("result")
                app.logic.SendDataToOtherPlayer(); 
                value= app.logic.WaitForOtherPlayer();
                app.updateUI();

            end
              
            
            app.CurrentPlayerLabel.Text = message;
            app.updateUI();


        end

        % Button pushed function: HoldButton
        function HoldButtonPushed(app, event)
            app.UITable.Data = [];
            set(app.RollDiceButton, 'Enable', 'off');
            set(app.HoldButton, 'Enable', 'off');
            app.updateUIHold();
            app.logic.resetScore();
            app.logic.SendDataToOtherPlayer();            value= app.logic.WaitForOtherPlayer();
            updateUI(app);
            set(app.RollDiceButton, 'Enable', 'on');
            set(app.HoldButton, 'Enable', 'on');

        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            set(app.Player1Button, 'Enable', 'off');
            set(app.Player2Button, 'Enable', 'off');
            set(app.BeginButton, 'Enable', 'off');
   
           if (app.logic.myPlayerNum == 1)
                set(app.RollDiceButton, 'Enable', 'on');
                set(app.HoldButton, 'Enable', 'on');
            % 
            else % app.myPlayerNum == 2
            % 
             app.logic.WaitForOtherPlayer();
             app.updateUI();
             set(app.RollDiceButton, 'Enable', 'on');
             set(app.HoldButton, 'Enable', 'on');


             end   
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           app.StartPanel.Visible = 'off';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.Player1Button
                app.logic.myPlayerNum= 1;
                app.logic.otherPlayerNum= 2;
            end
            if selectedButton == app.Player2Button
                app.logic.myPlayerNum= 2;
                app.logic.otherPlayerNum= 1;
            end
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            ruleFig = uifigure('Name', 'Rules', 'Position', [100, 100, 600, 300]);

            ruleText = uilabel(ruleFig, 'Text', ...
            "Rule 1: First player to fifty points wins!" + newline + ...
            "Rule 2: You can choose to either roll or hold during your turn" + newline + ...
            "Rule 3: If you roll a one, your turn ends and you lose all points accumulated during the turn" + newline + ...
            "Rule 4: If you choose to hold, your turn score is added to your total score",...
            'Position', [40, 80, 500, 200], 'FontSize', 12,'HorizontalAlignment','left');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.background = uiaxes(app.UIFigure);
            app.DiceAxes = uiaxes(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Rolls'};
            app.UITable.RowName = {};
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.HoldButton = uibutton(app.UIFigure, 'push');
            app.HoldButton.ButtonPushedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.HoldButton.Text = 'Hold';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll';
            app.CurrentPlayerLabel = uilabel(app.UIFigure);
            app.CurrentPlayerLabel.Text = 'Player 1''s Turn!';
            app.Player1ScoreLabel = uilabel(app.UIFigure);
            app.Player1ScoreLabel.Text = '0';
            app.Player2ScoreLabel = uilabel(app.UIFigure);
            app.Player2ScoreLabel.Text = '0';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'title1.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot_2025-03-03_at_9.33.45_PM-removebg-preview.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Screenshot_2025-03-03_at_9.37.20_PM-removebg-preview.png');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Player1Button = uiradiobutton(app.ButtonGroup);
            app.Player1Button.Text = 'Player 1';
            app.Player1Button.Value = true;
            app.Player2Button = uiradiobutton(app.ButtonGroup);
            app.Player2Button.Text = 'Player 2';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Screenshot_2025-03-04_at_10.34.59_PM-removebg-preview.png');
            app.StartPanel = uipanel(app.UIFigure);
            app.Image6 = uiimage(app.StartPanel);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Pig Game.png');
            app.StartButton = uibutton(app.StartPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end